classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotPanel                 matlab.ui.container.Panel
        Image3                    matlab.ui.control.Image
        TextArea                  matlab.ui.control.TextArea
        SlicesButton              matlab.ui.control.Button
        StructureButton           matlab.ui.control.Button
        ModelButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        TabGroup                  matlab.ui.container.TabGroup
        AdaptiveSlicingTab        matlab.ui.container.Tab
        SettingsPanel             matlab.ui.container.Panel
        STLfileDropDownLabel      matlab.ui.control.Label
        STLfileDropDown           matlab.ui.control.DropDown
        IncrementsEditFieldLabel  matlab.ui.control.Label
        IncrementsEditField       matlab.ui.control.NumericEditField
        CustomFileButton          matlab.ui.control.Button
        SimpleButton              matlab.ui.control.Button
        AdaptiveButton            matlab.ui.control.Button
        AdaptiveSlicingPanel      matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        CurvedLayerSlicingTab     matlab.ui.container.Tab
        SettingsPanel_2           matlab.ui.container.Panel
        STLfileDropDown_2Label    matlab.ui.control.Label
        STLfileDropDown_2         matlab.ui.control.DropDown
        CustomFileButton_2        matlab.ui.control.Button
        FreeFormSurfacePanel      matlab.ui.container.Panel
        NoofXEditFieldLabel       matlab.ui.control.Label
        NoofXEditField            matlab.ui.control.NumericEditField
        CreatefreeformButton      matlab.ui.control.Button
        NoofYEditFieldLabel       matlab.ui.control.Label
        NoofYEditField            matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel  matlab.ui.control.Label
        ResolutionEditField       matlab.ui.control.NumericEditField
        CurvedLayerSlicingPanel   matlab.ui.container.Panel
        NoofXEditField_2Label     matlab.ui.control.Label
        NoofXEditField_2          matlab.ui.control.NumericEditField
        CurvedLayerSliceButton    matlab.ui.control.Button
        NoofYEditField_2Label     matlab.ui.control.Label
        NoofYEditField_2          matlab.ui.control.NumericEditField
        PointsEditFieldLabel      matlab.ui.control.Label
        PointsEditField           matlab.ui.control.NumericEditField
        PathsEditFieldLabel       matlab.ui.control.Label
        PathsEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Property % Description
    % Variables for GUI
    img
    file = "Pyramid.stl"
    increments = 1;
    numberX = 10;
    numberY = 10;
    resolution = 30;
    numberX2 = 5;
    numberY2 = 5;
    numberPoints = 30;
    numberPaths = 30;
    % Todo, grab below values from UI tble
    thicknessArray = {0,0.1,0.2,0.5,1.0};
    angleArray = {90, 85, 79, 65, 0};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('AdaptiveParameters.xlsx');
            
            app.UITable.Data = t;
            app.UITable.ColumnEditable = true;
            plot3(app.UIAxes, 1, 1, 1, '-r');
        end

        % Button pushed function: CustomFileButton
        function CustomFileButtonPushed(app, event)
            app.file = uigetfile;
        end

        % Button pushed function: SimpleButton
        function SimpleButtonPushed(app, event)
            Slicing("Models/" + app.file, app.increments);
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Slicing...")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
        end

        % Button pushed function: SlicesButton
        function SlicesButtonPushed(app, event)
            app.img = imread("slicing2.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: StructureButton
        function StructureButtonPushed(app, event)
            app.img = imread("structure2.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            app.img = imread("model2.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Value changed function: STLfileDropDown
        function STLfileDropDownValueChanged(app, event)
            value = app.STLfileDropDown.Value;
            app.file = value + ".stl";
        end

        % Value changed function: IncrementsEditField
        function IncrementsEditFieldValueChanged(app, event)
            value = app.IncrementsEditField.Value;
            app.increments = value;
        end

        % Button pushed function: AdaptiveButton
        function AdaptiveButtonPushed(app, event)

            AdaptiveSlicingScript("Models/" + app.file, app.increments, app.thicknessArray, app.angleArray);
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Adaptive Slicing...")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
        end

        % Button pushed function: CreatefreeformButton
        function CreatefreeformButtonPushed(app, event)
            FFS_Script("Models/" + app.file, app.numberX, app.numberY, app.resolution);
        end

        % Button pushed function: CurvedLayerSliceButton
        function CurvedLayerSliceButtonPushed(app, event)
            CutterPath_Script("Models/" + app.file, app.numberX2, app.numberY2, app.numberPoints, app.numberPaths);
        end

        % Value changed function: NoofXEditField
        function NoofXEditFieldValueChanged(app, event)
            value = app.NoofXEditField.Value;
            app.numberX = value;
        end

        % Value changed function: NoofYEditField
        function NoofYEditFieldValueChanged(app, event)
            value = app.NoofYEditField.Value;
            app.numberY = value;
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.resolution = value;
        end

        % Value changed function: NoofXEditField_2
        function NoofXEditField_2ValueChanged(app, event)
            value = app.NoofXEditField_2.Value;
            app.numberX2 = value;
        end

        % Value changed function: NoofYEditField_2
        function NoofYEditField_2ValueChanged(app, event)
            value = app.NoofYEditField_2.Value;
            app.numberY2 = value;
        end

        % Value changed function: PointsEditField
        function PointsEditFieldValueChanged(app, event)
            value = app.PointsEditField.Value;
            app.numberPoints = value;
        end

        % Value changed function: PathsEditField
        function PathsEditFieldValueChanged(app, event)
            value = app.PathsEditField.Value;
            app.numberPaths = value;
        end

        % Value changed function: STLfileDropDown_2
        function STLfileDropDown_2ValueChanged(app, event)
            value = app.STLfileDropDown_2.Value;
            app.file = value + ".stl";
        end

        % Button pushed function: CustomFileButton_2
        function CustomFileButton_2Pushed(app, event)
            app.file = uigetfile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.Image3 = uiimage(app.PlotPanel);
            app.Image3.ImageSource = 'default.jpg';
            app.TextArea = uitextarea(app.PlotPanel);
            app.SlicesButton = uibutton(app.PlotPanel, 'push');
            app.SlicesButton.ButtonPushedFcn = createCallbackFcn(app, @SlicesButtonPushed, true);
            app.SlicesButton.Text = 'Slices';
            app.StructureButton = uibutton(app.PlotPanel, 'push');
            app.StructureButton.ButtonPushedFcn = createCallbackFcn(app, @StructureButtonPushed, true);
            app.StructureButton.Text = 'Structure';
            app.ModelButton = uibutton(app.PlotPanel, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Text = 'Model';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdaptiveSlicingTab = uitab(app.TabGroup);
            app.AdaptiveSlicingTab.Title = 'Adaptive Slicing';
            app.SettingsPanel = uipanel(app.AdaptiveSlicingTab);
            app.SettingsPanel.Title = 'Settings';
            app.STLfileDropDownLabel = uilabel(app.SettingsPanel);
            app.STLfileDropDownLabel.Text = {'STL file'; ''};
            app.STLfileDropDown = uidropdown(app.SettingsPanel);
            app.STLfileDropDown.Items = {'Cone', 'Pyramid', 'Cube', 'AngleChange', 'ProtrudingPart', '', ''};
            app.STLfileDropDown.ValueChangedFcn = createCallbackFcn(app, @STLfileDropDownValueChanged, true);
            app.STLfileDropDown.Value = 'Cone';
            app.IncrementsEditFieldLabel = uilabel(app.SettingsPanel);
            app.IncrementsEditFieldLabel.Text = 'Increments';
            app.IncrementsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.IncrementsEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementsEditFieldValueChanged, true);
            app.IncrementsEditField.Value = 1;
            app.CustomFileButton = uibutton(app.SettingsPanel, 'push');
            app.CustomFileButton.ButtonPushedFcn = createCallbackFcn(app, @CustomFileButtonPushed, true);
            app.CustomFileButton.Text = 'Custom File';
            app.SimpleButton = uibutton(app.SettingsPanel, 'push');
            app.SimpleButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleButtonPushed, true);
            app.SimpleButton.Text = 'Simple';
            app.AdaptiveButton = uibutton(app.SettingsPanel, 'push');
            app.AdaptiveButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveButtonPushed, true);
            app.AdaptiveButton.Text = 'Adaptive';
            app.AdaptiveSlicingPanel = uipanel(app.AdaptiveSlicingTab);
            app.AdaptiveSlicingPanel.Title = 'Adaptive Slicing';
            app.UITable = uitable(app.AdaptiveSlicingTab);
            app.UITable.ColumnName = {'Min Angle'; 'Max Angle'; 'Min Residue'; 'Max Residue'; 'Thickness'};
            app.UITable.RowName = {};
            app.CurvedLayerSlicingTab = uitab(app.TabGroup);
            app.CurvedLayerSlicingTab.Title = 'Curved Layer Slicing';
            app.SettingsPanel_2 = uipanel(app.CurvedLayerSlicingTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.STLfileDropDown_2Label = uilabel(app.SettingsPanel_2);
            app.STLfileDropDown_2Label.Text = {'STL file'; ''};
            app.STLfileDropDown_2 = uidropdown(app.SettingsPanel_2);
            app.STLfileDropDown_2.Items = {'Cylinder', 'Pyramid', 'Crater', 'Temple', '', ''};
            app.STLfileDropDown_2.ValueChangedFcn = createCallbackFcn(app, @STLfileDropDown_2ValueChanged, true);
            app.STLfileDropDown_2.Value = 'Cylinder';
            app.CustomFileButton_2 = uibutton(app.SettingsPanel_2, 'push');
            app.CustomFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @CustomFileButton_2Pushed, true);
            app.CustomFileButton_2.Text = 'Custom File';
            app.FreeFormSurfacePanel = uipanel(app.CurvedLayerSlicingTab);
            app.FreeFormSurfacePanel.Title = 'Free Form Surface';
            app.NoofXEditFieldLabel = uilabel(app.FreeFormSurfacePanel);
            app.NoofXEditFieldLabel.Text = 'No. of X';
            app.NoofXEditField = uieditfield(app.FreeFormSurfacePanel, 'numeric');
            app.NoofXEditField.ValueChangedFcn = createCallbackFcn(app, @NoofXEditFieldValueChanged, true);
            app.NoofXEditField.Value = 10;
            app.CreatefreeformButton = uibutton(app.FreeFormSurfacePanel, 'push');
            app.CreatefreeformButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefreeformButtonPushed, true);
            app.CreatefreeformButton.Text = 'Create free form';
            app.NoofYEditFieldLabel = uilabel(app.FreeFormSurfacePanel);
            app.NoofYEditFieldLabel.Text = 'No. of Y';
            app.NoofYEditField = uieditfield(app.FreeFormSurfacePanel, 'numeric');
            app.NoofYEditField.ValueChangedFcn = createCallbackFcn(app, @NoofYEditFieldValueChanged, true);
            app.NoofYEditField.Value = 10;
            app.ResolutionEditFieldLabel = uilabel(app.FreeFormSurfacePanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.FreeFormSurfacePanel, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 30;
            app.CurvedLayerSlicingPanel = uipanel(app.CurvedLayerSlicingTab);
            app.CurvedLayerSlicingPanel.Title = 'Curved Layer Slicing';
            app.NoofXEditField_2Label = uilabel(app.CurvedLayerSlicingPanel);
            app.NoofXEditField_2Label.Text = 'No. of X';
            app.NoofXEditField_2 = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.NoofXEditField_2.ValueChangedFcn = createCallbackFcn(app, @NoofXEditField_2ValueChanged, true);
            app.NoofXEditField_2.Value = 5;
            app.CurvedLayerSliceButton = uibutton(app.CurvedLayerSlicingPanel, 'push');
            app.CurvedLayerSliceButton.ButtonPushedFcn = createCallbackFcn(app, @CurvedLayerSliceButtonPushed, true);
            app.CurvedLayerSliceButton.Text = 'Curved Layer Slice';
            app.NoofYEditField_2Label = uilabel(app.CurvedLayerSlicingPanel);
            app.NoofYEditField_2Label.Text = 'No. of Y';
            app.NoofYEditField_2 = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.NoofYEditField_2.ValueChangedFcn = createCallbackFcn(app, @NoofYEditField_2ValueChanged, true);
            app.NoofYEditField_2.Value = 5;
            app.PointsEditFieldLabel = uilabel(app.CurvedLayerSlicingPanel);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.PointsEditField.ValueChangedFcn = createCallbackFcn(app, @PointsEditFieldValueChanged, true);
            app.PointsEditField.Value = 30;
            app.PathsEditFieldLabel = uilabel(app.CurvedLayerSlicingPanel);
            app.PathsEditFieldLabel.Text = 'Paths';
            app.PathsEditField = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.PathsEditField.ValueChangedFcn = createCallbackFcn(app, @PathsEditFieldValueChanged, true);
            app.PathsEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end